classdef gloves_defect_detection_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GlovesDefectDetectionGDDSystemLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LatexGloveTab                   matlab.ui.container.Tab
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        ImageEditField                  matlab.ui.control.EditField
        ImageLabel                      matlab.ui.control.Label
        LatexGloveDefectDetectionLabel  matlab.ui.control.Label
        ImportImageButton               matlab.ui.control.Button
        InitiateDetectionButton         matlab.ui.control.Button
        DefectinformationPanel          matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        DefectsLabel                    matlab.ui.control.Label
        DetectedgloveLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ClothGloveTab                   matlab.ui.container.Tab
        ImageEditField_3                matlab.ui.control.EditField
        ImageEditField_3Label           matlab.ui.control.Label
        ImportImageButton_3             matlab.ui.control.Button
        ClothGloveDefectDetectionLabel  matlab.ui.control.Label
        numBoundingBoxes                matlab.ui.control.Label
        DefectsDetectedLabel            matlab.ui.control.Label
        DetectButton                    matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        TextArea_2                      matlab.ui.control.TextArea
        UIAxes_3                        matlab.ui.control.UIAxes
        FoamGloveTab                    matlab.ui.container.Tab
        SelectDetectionTypePanel        matlab.ui.container.Panel
        WristTearingButton              matlab.ui.control.Button
        SmallCutWristButton             matlab.ui.control.Button
        CutButton                       matlab.ui.control.Button
        FoamGloveDefectDetectionLabel   matlab.ui.control.Label
        ImageEditField_2                matlab.ui.control.EditField
        ImageEditField_2Label           matlab.ui.control.Label
        ImportImageButton_2             matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [filename, path] = uigetfile({'*.jpeg','*.jpg'}, 'Select image');
            if filename ~= 0
                app.ImageEditField.Value =  filename;
                fullname = fullfile(path, filename);
                a = imread(fullname);
                imshow(a,'Parent', app.UIAxes);
            end
        end

        % Value changed function: ImageEditField
        function ImageEditFieldValueChanged(app, event)
            value = app.ImageEditField.Value;
            
        end

        % Button pushed function: InitiateDetectionButton
        function InitiateDetectionButtonPushed(app, event)
            % 001 Start defect detection program for latex glove
            imageFile = imhandles(app.UIAxes);
            orientation = app.OrientationDropDown.Value;

            gloveType = "NULL";
            input_image = get(imageFile, 'CData');
            finger_defect_name=[];
            finger_defect_bounding_box = [];

            stain_or_hole_defect_name=[]; 
            stain_or_hole_defect_bounding_box=[];

            if isempty(imageFile)
                errordlg('Error: You did not load image!','Error Message','modal');
            elseif orientation == "Select"
                errordlg('Error: You did not select an orientation for defect detection!','Error Message','modal');

            else
                % Identify whether glove is correct
                input_image = get(imageFile, 'CData');
                hsvImage = rgb2hsv(input_image);

                hue = hsvImage(:,:,1);
                saturation = hsvImage(:,:,2);
                value = hsvImage(:,:,3);

                blueMask = hue >= 0.55 & hue <= 0.67 & saturation >= 0.2 & value >= 0.2;
                bluePixelCount = nnz(blueMask);
                bluePercentage = bluePixelCount / numel(blueMask) * 100;
                
                disp(['Percentage of "blue" in the image: ' num2str(bluePercentage) '%'])

                % Decide whether the image is a blue latex glove or not
                if (bluePercentage >= 5)
                    gloveType = "Latex glove";
                    
                    % Continue function here to detect defects work in progress...
                    if (orientation == "Palm")

                        % COUNT FINGERS
                        
                        % Convert to HSV image
                        hsvImage = rgb2hsv(input_image);
                        % Extract saturation channel
                        hsvImage = hsvImage(:,:,2);
                        
                        % Extract the glove region

                        % Convert HSV Image to Black and White
                        blackAndWhiteImage = imbinarize(hsvImage,0.45);

                        % Smooth the boundaries and remove noise in the
                        % black and white image
                        % Create a structure element using strel() for
                        % morphological image processing.
                        structureElement = strel('disk',5);
                        % imclose() is used to close up any open edges that
                        % may be in the image and smoothen the boundaries 
                        % of the image by performing dilation and erosion
                        blackAndWhiteImage = imclose(blackAndWhiteImage,structureElement);
                        % bwareaopen() is used to remove any noises in the
                        % image by removing any pixel group that has less
                        % than 100k pixels
                        blackAndWhiteImage = bwareaopen(blackAndWhiteImage, 100000);
                        % imfill() is used to fill up any holes in the
                        % binary image to ensure that end result is only
                        % the glove region.
                        blackAndWhiteImage = imfill(blackAndWhiteImage,'holes');
                        figure('Name', 'Pre-Processing - Missing Finger Defect (Count Fingers)'),
                        subplot(3,2,1), imshow(blackAndWhiteImage),title('Glove region');
                        
                        % Palm region extraction
                        palmStructureElement = strel('disk',50);
                        palmImage = imopen(blackAndWhiteImage, palmStructureElement);
                        % subplot(1,3,3), imshow(palm_img),title('Palm region');
                        
                        % Reduction of palm area in glove region
                        fingerImage = blackAndWhiteImage-palmImage;
                        fingerImage = imbinarize(fingerImage);
                        % Accept size with greater or equal to 5000 pixels (finger size)
                        sizeThreshold = 5000;
                        fingerImage = bwpropfilt(fingerImage, 'Area', [sizeThreshold Inf]);
                        %subplot(1,3,3),imshow(finger), title('Fingers extracted');
                        
                        %Record number of fingers
                        [label, numberOfFinger]  = bwlabel(fingerImage);
                        fprintf('Number of fingers: %d',numberOfFinger);
                        if (numberOfFinger ~= 5)
                            disp('Missing Finger(s)');

                            % Proceed with finger detection for PALM
                            % because there is missing finger(s)

                            % Transform Image to HSV
                            hsv_Image = rgb2hsv(input_image);
                            
                            % Extract Saturation Channel
                            hsv_Image = hsv_Image(:,:,2);

                            % Set Threshold for PALM
                            threshold_value = 0.3;

                            % Use Threshold to Isolate Glove and The Finger Region
                            blackAndWhiteImage = imbinarize(hsv_Image,threshold_value); 

                            % Remove small pixels that are too small
                            blackAndWhiteImage = bwareaopen(blackAndWhiteImage,300); 

                            % Remove noise
                            blackAndWhiteImage = medfilt2(blackAndWhiteImage,[5 5]);

                            % Otsu Threshold Method for Glove Region
                            BW = graythresh(hsv_Image);
                            binarized = imbinarize(hsv_Image,BW);

                            % Remove Noise of Glove Region
                            binarized = imfill(binarized,'hole');

                            % Remove small pixels that are too small
                            binarized = bwareaopen(binarized,300);
                            
                            % Obtaining Defect Region
                            % `defect_in` = Defect Within Glove Region
                            % `defect_out` = Defect Out of Glove Region
                            defect_in = blackAndWhiteImage-binarized;
                            defect_out = binarized-blackAndWhiteImage;
                            defect = defect_in|defect_out;
                            defect = imfill(defect,'hole');

                            % Filter Noises and Small White Regions
                            defect = medfilt2(defect,[7 7]);
                            defect = bwareaopen(defect,1000);
                            % Smoothen the Defect Region
                            structuredElement = strel('disk', 5);
                            defect = imclose(defect,structuredElement);
                            
                            % Label the Defect region
                            [Ilabel, num] = bwlabel(defect);
                            disp("Possible Finger Defect Count = " + num);
                            Iprops = regionprops(Ilabel,'BoundingBox','Area');
                            Ibox = [Iprops.BoundingBox];
                            IArea = [Iprops.Area];
                            Ibox = reshape(Ibox, [4 num]);
                            finger_defect_bounding_box = [];

                            % Remove Extra Small & Big Regions
                            for i = 1: length(IArea)
                                disp("i=" + i);
                                disp("Area=" + IArea(i));
                                if ((IArea(i) > 2000) && (IArea(i) < 100000))
                                    finger_defect_bounding_box(:,end+1) = Ibox(:,i);
                                end
                            end

                            [rows,columns] = size(finger_defect_bounding_box);
                            subplot(3,2,2),imshow(hsv_Image),title('HSV Saturation Image [rgb2hsv()]');
                            subplot(3,2,3),imshow(blackAndWhiteImage),title('Saturation Thresholded Image [medfilt2()]');
                            subplot(3,2,4),imshow(binarized), title('Glove region mask [imbinarize() > imfill() > bwareaopen()]');
                            subplot(3,2,5),imshow(defect), title('Possible defect [image substraction > fill > medfilt2 > bwareaopen > close (se)]');
                            
                            % Check Skin Coverage of Detected Defects
                            not_defect=[];
                            finger_defect_name = [];

                            for count = 1:columns
                                x = finger_defect_bounding_box(1, count);
                                y = finger_defect_bounding_box(2, count);
                                w = finger_defect_bounding_box(3, count);
                                h = finger_defect_bounding_box(4, count);

                                size(input_image);

                                % Extract Image from Bounding Box
                                defect_region = imcrop(input_image,[x, y, w, h]); 
                                defect_mask = imcrop(defect,[x, y, w, h]);
                                figure('Name',sprintf('Missing Finger - Defect %d', count)),
                                subplot(1,3,1),imshow(defect_mask), title('Defect Mask [imcrop() - Extract Defect]');

                                % Resize the mask image to have the same size as the input image
                                defect_mask = imresize(defect_mask, [size(defect_region,1) size(defect_region,2)]);
                                defect_mask = cast(defect_mask, 'uint8');
                            
                                % Replicate the mask image across the additional channels of the input image, if necessary
                                defect_mask = repmat(defect_mask, [1,1,3]);

                                % (Threshold) Invert defect mask (~) and set defect_mask area to white (255)
                                defect_region(~defect_mask)=255;
                                
                                subplot(1,3,2),imshow(defect_region), title('Defect region');
                                % Convert The Image to the HSV Color Space
                                ycbcrImg = rgb2ycbcr(defect_region);
                                
                                % Define the Lower and Upper Bounds of The Skin Color Cluster
                                lower = [0, 110, 129];
                                upper = [142, 131, 165];
                                
                                % Threshold the HSV Image to Create a Binary Mask
                                mask = ycbcrImg(:,:,1) >= lower(1) & ycbcrImg(:,:,1) <= upper(1) & ...
                                       ycbcrImg(:,:,2) >= lower(2) & ycbcrImg(:,:,2) <= upper(2) & ...
                                       ycbcrImg(:,:,3) >= lower(3) & ycbcrImg(:,:,3) <= upper(3);

                                subplot(1,3,3), imshow(mask),title ('Skin region in defect region');

                                % Use The Mask to Segment The Image Into Regions
                                regions = regionprops(mask, 'Area');

                                % Compute The Total Area of The Mask
                                totalArea = size(defect_region, 1) * size(defect_region, 2);
                                
                                % Compute The Percentage of Skin-Coloured Regions in Image
                                skinCoverage = 100 * sum([regions.Area]) / totalArea;
                                
                                % Print The Percentage of Skin-Coloured Regions
                                fprintf('Skin coverage in missing finger defect: %.2f%%\n', skinCoverage);

                                if (skinCoverage <20)
                                    not_defect = cat(2, not_defect,count);
                                    not_defect = sort(not_defect, 'descend');
                                end
                            
                            end

                            % Remove Fail Defect
                            for i = 1: length(not_defect)
                                finger_defect_bounding_box(:,not_defect(i)) = [];
                            end
                            for count = 1:size(finger_defect_bounding_box,2)
                                finger_defect_name = cat(2, finger_defect_name,{'Missing Finger'});
                            end

                        else
                            disp('Finger enough (no need to proceed with fingers');
                        end
                    else

                        % Proceed with finger detection for ORIENTATION OTHER THAN PALM

                        % Transform Image to HSV
                        hsv_Image = rgb2hsv(input_image);
                        % Extract Saturation Channel
                        hsv_Image = hsv_Image(:,:,2);

                        % Set Threshold
                        if(orientation == "Fingertip")
                             threshold_value = 0.4;
                        else
                            % Side
                            threshold_value = 0.41;
                        end

                        % Use Threshold to Isolate Glove and The Finger Region
                        blackAndWhiteImage = imbinarize(hsv_Image,threshold_value); 
                        % Remove small pixels that are too small
                        blackAndWhiteImage = bwareaopen(blackAndWhiteImage,300); 

                        if(orientation == "Side")
                            dilate = strel('disk',3);
                            blackAndWhiteImage = imdilate(blackAndWhiteImage, dilate);
                        end

                        % Remove noise
                        blackAndWhiteImage = medfilt2(blackAndWhiteImage,[5 5]);
                        % Otsu Threshold Method for Glove Region
                        BW = graythresh(hsv_Image);
                        binarized = imbinarize(hsv_Image,BW);
                        % Remove Noise of Glove Region
                        binarized = imfill(binarized,'hole');
                        % Remove small pixels that are too small
                        binarized = bwareaopen(binarized,300);
                        
                        % Obtaining Defect Region
                        % `defect_in` = Defect Within Glove Region
                        % `defect_out` = Defect Out of Glove Region
                        defect_in = blackAndWhiteImage-binarized;
                        defect_out = binarized-blackAndWhiteImage;
                        defect = defect_in|defect_out;
                        defect = imfill(defect,'hole');

                        % Filter Noises and Small White Regions
                        defect = medfilt2(defect,[7 7]);
                        defect = bwareaopen(defect,1000);
                        % Smoothen the Defect Region
                        structuredElement = strel('disk', 5);
                        defect = imclose(defect,structuredElement);
                        
                        % Label the Defect region
                        [Ilabel, num] = bwlabel(defect);
                        disp("Possible Finger Defect Count = " + num);
                        Iprops = regionprops(Ilabel,'BoundingBox','Area');
                        Ibox = [Iprops.BoundingBox];
                        IArea = [Iprops.Area];
                        Ibox = reshape(Ibox, [4 num]);
                        finger_defect_bounding_box = [];

                        % Remove Extra Small & Big Regions
                        for i = 1: length(IArea)
                            disp("i=" + i);
                            disp("Area=" + IArea(i));
                            if ((IArea(i) > 2000) && (IArea(i) < 100000))
                                finger_defect_bounding_box(:,end+1) = Ibox(:,i);
                            end
                        end

                        [rows,columns] = size(finger_defect_bounding_box);
                        figure('Name','Missing Finger Pre-processing'),
                        subplot(2,2,1),imshow(hsv_Image),title('HSV Saturation Image');
                        subplot(2,2,2),imshow(blackAndWhiteImage),title('Saturation Thresholded Image');
                        subplot(2,2,3),imshow(binarized), title('Glove region mask');
                        subplot(2,2,4),imshow(defect), title('Possible defect');
                        
                        % Check Skin Coverage of Detected Defects
                        not_defect=[];
                        finger_defect_name = [];

                        for count = 1:columns
                            x = finger_defect_bounding_box(1, count);
                            y = finger_defect_bounding_box(2, count);
                            w = finger_defect_bounding_box(3, count);
                            h = finger_defect_bounding_box(4, count);

                            size(input_image);
                            % Extract Image from Bounding Box
                            defect_region = imcrop(input_image,[x, y, w, h]); 
                            defect_mask = imcrop(defect,[x, y, w, h]);
                            figure('Name',sprintf('Defect %d', count)), subplot(1,3,1),imshow(defect_mask), title('Defect mask');
                            % Resize the mask image to have the same size as the input image
                            defect_mask = imresize(defect_mask, [size(defect_region,1) size(defect_region,2)]);
                            defect_mask = cast(defect_mask, 'uint8');
                        
                            % Replicate the mask image across the additional channels of the input image, if necessary
                            defect_mask = repmat(defect_mask, [1,1,3]);
                            % defect = bsxfun(@times, defect_region, cast(defect_mask, 'like', defect_region));
                            defect_region(~defect_mask)=255;
                            
                            subplot(1,3,2),imshow(defect_region), title('Defect region');
                            % Convert The Image to the HSV Color Space
                            ycbcrImg = rgb2ycbcr(defect_region);
                            
                            % Define the Lower and Upper Bounds of The Skin Color Cluster
                            lower = [0, 110, 129];
                            upper = [142, 131, 165];
                            
                            % Threshold the HSV Image to Create a Binary Mask
                            mask = ycbcrImg(:,:,1) >= lower(1) & ycbcrImg(:,:,1) <= upper(1) & ...
                                   ycbcrImg(:,:,2) >= lower(2) & ycbcrImg(:,:,2) <= upper(2) & ...
                                   ycbcrImg(:,:,3) >= lower(3) & ycbcrImg(:,:,3) <= upper(3);

                            subplot(1,3,3), imshow(mask),title ('Skin region in defect region');

                            % Use The Mask to Segment The Image Into Regions
                            regions = regionprops(mask, 'Area');

                            % Compute The Total Area of The Mask
                            totalArea = size(defect_region, 1) * size(defect_region, 2);
                            
                            % Compute The Percentage of Skin-Coloured Regions in Image
                            skinCoverage = 100 * sum([regions.Area]) / totalArea;
                            
                            % Print The Percentage of Skin-Coloured Regions
                            fprintf('Skin coverage in missing finger defect: %.2f%%\n', skinCoverage);

                            if (skinCoverage <20)
                                not_defect = cat(2, not_defect,count);
                                not_defect = sort(not_defect, 'descend');
                            end
                        
                        end

                        % Remove Fail Defect
                        for i = 1: length(not_defect)
                            finger_defect_bounding_box(:,not_defect(i)) = [];
                        end

                        for count = 1:size(finger_defect_bounding_box,2)
                            finger_defect_name = cat(2, finger_defect_name,{'Missing Finger'});
                        end
                    end
                        % Proceed with DIRTY/STAIN and HOLE detection

                        % Transform image to HSV
                        hsvImage = rgb2hsv(input_image);

                        % Extract Saturation Channel
                        I1= hsvImage(:,:,2);

                        % Thresh away Low Saturation Pixel
                        % Threshold to Isolate Glove
                        thresholded = I1 > 0.4; 

                        % Remove Extra Small Pixels
                        thresholded = bwareaopen(thresholded,100);

                        % Apply Threshold
                        I2 = thresholded.*I1; 
                        % Otsu Threshold To Extract Glove Region
                        BlackWhite = graythresh(I2);
                        I3 = imbinarize(I2, BlackWhite);

                        % Remove Noise in Glove
                        I3 = imfill(I3,'hole');
                        I3 = medfilt2(I3,[5,5]);

                        % Erode Glove Region
                        structuredElement = strel("disk",5);
                        I3 = imerode(I3, structuredElement);

                        % Fill to Ensure There is only White Region in Binary Image
                        I3 = bwareafilt(I3,1);

                        % Mask Out The Glove Region
                        I4 = I2.*I3;

                        % Single Threshold Segmentation
                        s = size(I4); 

                        if(orientation=="Side")
                            threshold_value = 0.69;
                        else
                            % Fingertip & Palm
                            threshold_value = 0.5;
                        end
                        segment = zeros(s(1),s(2));

                        for i = 1:s(1)
                            for j = 1:s(2)
                                % Side = 0.69, Fingertip/Palm = 0.5
                                if(I4(i,j) >= threshold_value)
                                    segment(i,j) = 0;
                                else
                                    segment(i,j) = 255;
                                end
                            end
                        end

                        % Show Defects Regions
                        I4 = I3-(~segment);

                        % Smooth Defect using imclose()
                        I4 = imclose(I4,structuredElement);

                        %figure('Name','segment'),imshow(segment),title('Segmented Image');

                        % Blob up Possible Defect Region 
                        [Ilabel, num] = bwlabel(I4);
                        disp("Total possible defect = " + num);
                        Iprops = regionprops(Ilabel,'BoundingBox','Area');
                        Ibox = [Iprops.BoundingBox];
                        IArea = [Iprops.Area];
                        Ibox = reshape(Ibox, [4 num]);
                        stain_or_hole_defect_bounding_box = [];

                        % Remove Extra Small or Big Defects
                        for i = 1: length(IArea)
                            disp("i = " + i);
                            disp("Area = "+IArea(i));
                            if ((IArea(i) > 100) && (IArea(i) < 6000))
                                stain_or_hole_defect_bounding_box(:,end+1) = Ibox(:,i);
                            end
                        end
                        [rows,columns] = size(stain_or_hole_defect_bounding_box);
                        
                        figure('Name','Stained/Dirt Defect - Pre-Processing'),
                        subplot(2,2,1), imshow(I1), title('HSV Saturation Image');
                        subplot(2,2,2), imshow(I2), title('Saturation Thresholded Image');
                        subplot(2,2,3), imshow(I3), title('Glove region mask');
                        subplot(2,2,4), imshow(I4), title('Possible defects');
                        
                        stain_or_hole_defect_name = [];
                        not_defect = [];
                        for count = 1:columns
                            x = stain_or_hole_defect_bounding_box(1,count);
                            y = stain_or_hole_defect_bounding_box(2,count);
                            w = stain_or_hole_defect_bounding_box(3,count);
                            h = stain_or_hole_defect_bounding_box(4,count);
                            size(segment);

                            % Extract Image from Bounding Box
                            defect_mask = imcrop(segment,[x, y, w, h]);
                            defect_region = imcrop(input_image,[x, y, w, h]);

                            % Resize Mask Image to Original Image Size
                            defect_mask = imresize(defect_mask, [size(defect_region,1) size(defect_region,2)]);
                            defect_mask = cast(defect_mask,'uint8');
                        
                            % Replicate the mask image across the additional channels of the input image, if necessary
                            defect_mask = repmat(defect_mask, [1,1,3]);
                            defect_region(~defect_mask)=255;
                            figure('Name',sprintf('Stained/Hole Defect %d',count)), subplot(2,2,1),imshow(defect_region), title('Defect detected (Stain/Hole)');
                            subplot(2,2,2),imshow(defect_mask), title('Segmented mask');
                            
                            defectHsvImage = rgb2hsv(defect_region);
                            % Extract the Hue Channel
                            hue = defectHsvImage(:,:,1);
                            saturation = defectHsvImage(:,:,2);
                            value = defectHsvImage(:,:,3);

                            % Threshold the Hue Channel to Identify Blue Pixels (Hue)
                            bluemask = hue >= 0.55 & hue <= 0.67 & saturation >= 0.2 & value >= 0.2;
                            subplot(2,2,3), imshow(bluemask),title ('Blue region in defect region');

                            % Count The Number of Blue Pixels
                            numblue = nnz(bluemask);
                            % Calculate Percentage of Blue in Image
                            bluePercentage = numblue / numel(bluemask) ;
                            whiteCount = nnz(defect_mask);
                            whitePercentage = whiteCount / numel(defect_mask);
                            percentage = bluePercentage/whitePercentage * 100;
                            
                            % Display the result
                            disp(['Percentage of blue in stained/hole defects: ' num2str(percentage) '%'])
                            
                            % Conditionings
                            if (percentage < 50)
                                % Convert Image to YCbcr Color Space
                                ycbcrImg = rgb2ycbcr(defect_region);
                            
                                % Define the lower and upper bounds of the skin color cluster
                                lower = [80, 110, 129];
                                upper = [210, 145, 165];
                                
                                % Threshold the HSV image to create a binary mask
                                mask = ycbcrImg(:,:,1) >= lower(1) & ycbcrImg(:,:,1) <= upper(1) & ...
                                       ycbcrImg(:,:,2) >= lower(2) & ycbcrImg(:,:,2) <= upper(2) & ...
                                       ycbcrImg(:,:,3) >= lower(3) & ycbcrImg(:,:,3) <= upper(3);
                                subplot(2,2,4), imshow(mask),title ('Skin region in defect region');

                                % Use the mask to segment the image into regions
                                regions = regionprops(mask, 'Area');
                                % Compute the total area of the image
                                totalArea = size(defect_region, 1) * size(defect_region, 2);
                                % Compute the percentage of skin-colored regions in the image
                                skinCoverage = 100 * sum([regions.Area]) / totalArea;
                                
                                % Print the percentage of skin-colored regions
                                fprintf('Skin coverage in stained/hole defects: %.2f%%\n', skinCoverage);
                        
                                if (skinCoverage > 1 )
                                    % Set as Hole defect
                                    stain_or_hole_defect_name = cat(2, stain_or_hole_defect_name,{'Hole'});
                                else
                                    % Set as Dirty and stain
                                    stain_or_hole_defect_name = cat(2, stain_or_hole_defect_name,{'Stained And Dirty'});
                                end
                        
                            else
                                % Fail defect
                                not_defect = cat(2, not_defect, count);
                                not_defect = sort(not_defect, 'descend');
                            end
                        end

                        % Remove fail defect
                        for i = 1: length(not_defect)
                            stain_or_hole_defect_bounding_box(:,not_defect(i)) = [];
                        end
                 else
                    gloveType = "Not latex glove";
                    errordlg('The image given is not a blue latex glove', 'Error Message','modal');
                end
            end

            % Get number of missing fingers
            numberOfMissingFingers = length(finger_defect_name);
            numberOfDirtyOrStainedDefects = sum(strcmp(stain_or_hole_defect_name, 'Stained And Dirty'));
            numberOfHoleDefect = sum(strcmp(stain_or_hole_defect_name,'Hole'));
            numberOfMissingFingers = num2str(numberOfMissingFingers);
            numberOfDirtyOrStainedDefects = num2str(numberOfDirtyOrStainedDefects);
            numberOfHoleDefect = num2str(numberOfHoleDefect);

            % Create defect list to display result
            finger_defect_list = strcat("Missing Finger Defect: ", numberOfMissingFingers);
            stain_defect_list = strcat("Stained And Dirty Defect: ", numberOfDirtyOrStainedDefects);
            hole_defect_list = strcat("Hole Defect: ", numberOfHoleDefect);

            % Compile defect list
            defect_list = sprintf('%s\n%s\n%s', finger_defect_list, stain_defect_list, hole_defect_list);
            defect_name = cat(2, finger_defect_name, stain_or_hole_defect_name);
            defect_box = horzcat(finger_defect_bounding_box, stain_or_hole_defect_bounding_box);

            % Set handles
            app.EditField.Value =  gloveType;
            %set(handles.text6, 'String', orientation);
            app.TextArea.Value = defect_list;
            %set(handles.text7,'String', defect_list);
            axis(app.UIAxes);
            % Display image
            figure('Name','Results'),
            imshow(input_image), title('Result of Defect');

            % Draw Bouding Box
            % Set Font Size and Color for Label
            fontSize = 8;
            fontColor = 'red';
            hold on;

            for count = 1:size(defect_box,2)
                rectangle('position', defect_box(:,count), 'EdgeColor','r');
                text(defect_box(1,count), defect_box(2,count)-25, defect_name(count), ...
                'FontSize', fontSize, 'Color', fontColor);
            end

            hold off;
        end

        % Button pushed function: ImportImageButton_2
        function ImportImageButton_2Pushed(app, event)
            [filename, path] = uigetfile({'*.jpg'}, 'Select image');
            if filename ~= 0
                app.ImageEditField_2.Value =  filename;
                fullname = fullfile(path, filename);
                a = imread(fullname);
                imshow(a,'Parent', app.UIAxes_2);
            end
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            imageFile = imhandles(app.UIAxes_2);
            if isempty(imageFile)
                errordlg('Error: You did not load an image!','Error Message','modal');
            else
                % Get image
                ori_img = get(imageFile, 'CData');

                % Convert to grayscalee
                img = rgb2gray(ori_img);
                
                % Threshold it
                img = img > 18;
                
                
                % Remove noise and bigger dots
                img = medfilt2(img, [9 9]);
                se = strel('disk', 7);
                img = imerode(img, se);
                
                
                % Outline -----------------------------------------------------
                % Load the image
                img2 = ori_img;
                
                % Convert the image to grayscale
                img2 = rgb2gray(img2);
                
                % Threshold the image to create a binary image
                img2 = img2 < 60;
                
                img2 = medfilt2(img2,[30 30]);
                
                
                % Get the perimeter of the object
                img2 = bwperim(img2);
                
                % Dilate the perimeter to thicken the outline
                se = strel('disk', 100);
                img2 = imdilate(img2, se);
                img2 = imdilate(img2, se);
                
                % Remove outline from first image
                final =  img + img2;
                final = imcomplement(final);
                
                % Merge both image
                final = imbinarize(final);
                
                % Cleaner Cut
                se = strel('disk',10);
                final = imerode(final,se);
                se = strel('disk',10);
                final = imdilate(final,se);


                %---------
                cc = bwconncomp(final);
                
                % Initialize variables
                max_length = 0;
                max_idx = 0;
                
                % Loop through all connected components
                for i = 1:cc.NumObjects
                    % Get the pixel coordinates of the current component
                    pixel_list = cc.PixelIdxList{i};
                    [rows,cols] = ind2sub(cc.ImageSize,pixel_list);
                    coords = [cols,rows];
                    
                    % Compute the length of the current component
                    length = size(coords,1);
                    
                    % Check if this is the longest component found so far
                    if length > max_length
                        max_length = length;
                        max_idx = i;
                    end
                end
                
                % Extract the pixel coordinates of the longest component
                pixel_list = cc.PixelIdxList{max_idx};
                [rows,cols] = ind2sub(cc.ImageSize,pixel_list);
                pixels = [cols,rows];
                
                bbox = [min(pixels(:,1)), min(pixels(:,2)), ...
                        max(pixels(:,1))-min(pixels(:,1)), max(pixels(:,2))-min(pixels(:,2))];
                
                figure,imshow(ori_img);
                hold on;
                rectangle('Position', bbox, 'EdgeColor', 'red', 'LineWidth', 2);
                hold off;
            end
        end

        % Button pushed function: SmallCutWristButton
        function SmallCutWristButtonPushed(app, event)
            imageFile = imhandles(app.UIAxes_2);
            if isempty(imageFile)
                errordlg('Error: You did not load an image!','Error Message','modal');
            else
                % Get image
                ori_img = get(imageFile, 'CData');

                % Thresholding
                img = rgb2gray(ori_img);
                img = img > 70;
                
                % Inverse the image
                img = imcomplement(img);
                
                % Remove unwanted noise in the gloves
                img = medfilt2(img,[30 30]);
                
                % Cracking the cut
                se = strel('disk',30);
                img = imerode(img,se);
                
                % Fill the cut
                se = strel('disk',100);
                img2 = imclose(img, se);
                
                % Compare
                final = img2 - img;
                
                
                % Plot each region, find the largest
                labeled = bwconncomp(final);
                props = regionprops(labeled, 'Area', 'BoundingBox');
                [~, index] = max([props.Area]);
                boundingBox = props(index).BoundingBox;
                figure;
                imshow(ori_img);
                hold on;
                rectangle('Position', boundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
                hold off;
            end
        end

        % Button pushed function: WristTearingButton
        function WristTearingButtonPushed(app, event)
            imageFile = imhandles(app.UIAxes_2);
            if isempty(imageFile)
                errordlg('Error: You did not load an image!','Error Message','modal');
            else
                % Get images
                ori_img = get(imageFile, 'CData');

                % Convert the image to grayscale/2d
                img = rgb2gray(ori_img);
                
                % Threshold binary
                img = img > 40;
                
                % Edges
                img = edge(img,'canny');
                
                img = imfill(img,'holes');
                
                % Concrete the object, seal the fingers part
                se = strel('disk', 100);
                img = imclose(img, se);
                
                % Remove wrist line if there are any
                se = strel('line',20,90);
                img = imerode(img,se);
                img_normal = imdilate(img, se);
                
                
                % Seal the bottom part
                se = strel('disk', 500);
                img_sealed = imclose(img_normal, se);
                
                % Sealed bottom part  - unsealed = the teared area
                final = img_sealed - img_normal;
                
                
                % Draw boxes around the teared area
                labeled = bwconncomp(final);
                props = regionprops(labeled, 'Area', 'BoundingBox');
                [~, index] = max([props.Area]);
                boundingBox = props(index).BoundingBox;
                figure;
                imshow(ori_img);
                hold on;
                rectangle('Position', boundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
                hold off;
            end
        end

        % Button pushed function: ImportImageButton_3
        function ImportImageButton_3Pushed(app, event)
            [File_Name, Path_Name] = uigetfile({'*.jpg'}, 'Select image');
            if File_Name ~= 0
                cla(app.UIAxes_3);
                app.ImageEditField_3.Value =  File_Name;
                fullname = fullfile(Path_Name, File_Name);
                im = imread(fullname);
                imshow(im,'Parent', app.UIAxes_3);
                disp(['File name: ' File_Name]);
                disp(['Path name: ' Path_Name]);
                disp(['Full name: ' fullname]);
                disp('UIAxes is being passed correctly');
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
           input = imhandles(app.UIAxes_3);
           detection_type = app.DropDown.Value;

            % Get the value of the pop-out menu
            if isempty(input)
               % Display an error message if the axes is empty
               errordlg('Error: You did not load image!','Error Message','modal');
            elseif detection_type == "Defect Type"
                errordlg('Error: You did not select a defect type for defect detection!','Error Message','modal');
            else
               % Get the image data from the input
               inputImg = get(input, 'CData');
                
               % Apply an entropy filter to the image
               entFilt = entropyfilt(inputImg);

               % Rescale the filtered image so that its values fall within the range [0, 1]
               entRescale = rescale(entFilt);

               % Convert the rescaled image to a binary image using a threshold of 0.5
               binary = im2bw(entRescale, 0.5);  

               % Remove objects from the binary image that have an area less than 2000 pixels
               BWao = bwareaopen(binary, 2000);

               % Create a 9x9 neighborhood of ones
               nhood = ones(9);

               % Perform a morphological closing operation on the binary image using the neighborhood
               closeBWao = imclose(BWao, nhood);

               % Fill the holes in the closed binary image
               mask = imfill(closeBWao, 'holes');

               % Count the number of cloth regions in the image (i.e., the number of non-zero pixels in the mask)
               numcloth = nnz(~mask);
                
               % Calculate the percentage of the image that is blue
               clothcover = numcloth / numel(mask) * 100;
                
               % Display the result
               disp(['Percentage of cloth in glove image: ' num2str(clothcover) '%'])
                
               % Show the input image and the thresholded texture image
               figure('Name','Pre-processing');
               subplot(2, 2, 1),imshow(inputImg),title('Input Image');
               subplot(2, 2, 2),imshow(binary),title('Thresholded Texture Image');
               subplot(2, 2, 3),imshow(BWao),title('Area-Opened Texture Image');
               subplot(2, 2, 4),imshow(closeBWao),title('Closed Texture Image');
            end


            if (clothcover >= 15)
                switch detection_type
                    case "Open Seam" % Open Seam Detection
                        % Converts the input RGB color image to the HSV color space
                        hsvImage = rgb2hsv(inputImg);
    
                        % Extract the H(1), S(2) and V(3) channel from the image
                        hue = hsvImage(:,:,1);
                        sat = hsvImage(:,:,2)*2.5; 
                        val = hsvImage(:,:,3);
                        
                        % This line converts the extracted channels back to the HSV color space and stores them in hue, sat, and val, respectively.
                        [hueInHSV, saturationInHSV, valueInHSV] = rgb2hsv(hue,sat,val);
                        
                        % This line creates a binary image by thresholding the hue channel
                        % to identify regions with hues that correspond to red
                        % which are represented by values near 0 and 1 in the hue channel
                        threshHue = ((hueInHSV > 1) + (hueInHSV < 0.5)) > 0;

                        % This line removes the boundary regions of the
                        % binary image using a morphological operation called "clear border"
                        % fills in any regions that touch the image border with zeros.
                        nobord = imclearborder(threshHue, 4);

                        % These lines perform morphological erosion and dilation operations on the binary image 
                        % These operations help to remove noise and smooth the edges of the remaining regions.
                        erosion = double(bwmorph(nobord,'erode',3)); % disk-shaped structuring element of radius 3 for erosion
                        dilation = double(bwmorph(erosion,'dilate',5)); % disk-shaped structuring element of radius 5 for erosion

                        % This line removes the boundary regions of the binary image
                        dilateNobord = imclearborder(dilation,4);
                        
                        % Labels the connected components in the binary image
                        [labeledImage, numRegions] = bwlabel(dilateNobord); % Return labeled image and number of connected components

                        % This line extracts the bounding boxes for the connected components in the labeled image
                        boundingBoxes = regionprops(labeledImage, 'BoundingBox');

                        %Display the image
                        axis(app.UIAxes_3);
                        imshow(inputImg, 'Parent', app.UIAxes_3);
                        hold(app.UIAxes_3, 'on');

                        % Counter variable for number of bounding boxes
                        numBoxes = 0;
                        % Plot the bounding box around each region.
                        for k = 1 : numRegions
                            thisBBox = boundingBoxes(k).BoundingBox;
                            rectangle('Position', thisBBox, 'EdgeColor', 'r','LineWidth',1, 'Parent', app.UIAxes_3);
                            numBoxes = numBoxes + 1;
                            break
                        end
                        % Display the number of bounding boxes
                        app.numBoundingBoxes.Text = num2str(numBoxes);

                        % Show the input image and the thresholded texture image
                        figure('Name','Pre-processing');
                        subplot(2, 3, 1),imshow(threshHue),title('Thresholded Texture Image');
                        subplot(2, 3, 2),imshow(nobord),title('Remove the Boundary Regions of Image');
                        subplot(2, 3, 3),imshow(erosion),title('Morphological Erosion Image');
                        subplot(2, 3, 4),imshow(dilation),title('Morphological Dilation Image');
                        subplot(2, 3, 5),imshow(dilateNobord),title('Remove the Boundary Regions from Dilation Image');


                    case "Tearing" % Tearing detection
                        % Convert image to HSV color space
                        hsvImage = rgb2hsv(inputImg);
                        
                        % Extract the Hue, Saturation, and Value channels
                        hue = hsvImage(:,:,1);
                        sat = hsvImage(:,:,2) * 2.5;
                        val = hsvImage(:,:,3);
                        
                        % Create a binary mask to segment out pixels with low or high Saturation
                        lowSat = (sat < 0.65);
                        highSat = (sat > 1);
                        saturationMask = lowSat | highSat;
                        
                        % Invert the saturation mask to keep the regions with moderate saturation
                        invert = imcomplement(saturationMask);
                        
                        % Remove objects touching the image border
                        nobord = imclearborder(invert, 4);
                        
                        % Dilate the mask to connect nearby pixels
                        se90 = strel('line', 20, 90);
                        se0 = strel('line', 20, 0);
                        dilation = imdilate(nobord, [se90 se0]);
                        
                        % Erode the mask to separate overlapping regions
                        erosion = bwmorph(dilation, 'erode', 1);
                        dilatErode = bwmorph(erosion, 'dilate', 10);
                        
                        % Remove objects touching the image border again
                        finalMask = imclearborder(dilatErode, 4);

                        % Label connected components in the mask and get the bounding box of the largest region
                        labeled = bwconncomp(finalMask);
                        props = regionprops(labeled, 'Area', 'BoundingBox');
                        [~, index] = max([props.Area]);
                        boundingBox = props(index).BoundingBox;

                        axis(app.UIAxes_3);
                        imagesc(inputImg, 'Parent', app.UIAxes_3);
                        hold(app.UIAxes_3, 'on');
                        rectangle('Position', boundingBox, 'EdgeColor', 'r', 'LineWidth', 1, 'Parent', app.UIAxes_3);
                        app.numBoundingBoxes.Text = '1'; % Display the number of bounding boxes as 1.

                        % Show the input image and the thresholded texture image
                        figure('Name','Pre-processing');
                        subplot(4, 3, 1),imshow(hue),title('Hue Image');
                        subplot(4, 3, 2),imshow(sat),title('Saturation Image');
                        subplot(4, 3, 3),imshow(val),title('Value Image');
                        subplot(4, 3, 4),imshow(saturationMask),title('saturationMask Image');
                        subplot(4, 3, 5),imshow(invert),title('invert Image');
                        subplot(4, 3, 6),imshow(nobord),title('nobord Image');
                        subplot(4, 3, 7),imshow(dilation),title('dilation Image');
                        subplot(4, 3, 8),imshow(erosion),title('erosion Image');
                        subplot(4, 3, 9),imshow(dilatErode),title('dilatErode Image');
                        subplot(4, 3, 10),imshow(finalMask),title('finalMask Image');

                    case "Unravelled Seam"
                        % Convert input image from RGB to HSV color space
                        hsvImage = rgb2hsv(inputImg);
                        
                        % Separate hue, saturation, and value channels
                        hue = hsvImage(:,:,1);
                        sat = hsvImage(:,:,2) * 5;
                        val = hsvImage(:,:,3);
                        
                        % Convert hue, saturation, and value channels to HSV color space
                        [hueInHSV, saturationInHSV, valueInHSV] = rgb2hsv(hue, sat, val);
                        
                        % Binarize saturation channel using Otsu's method
                        binarizeSat = imbinarize(saturationInHSV, graythresh(saturationInHSV));
                        
                        % Invert binarized saturation image
                        invert = imcomplement(binarizeSat);
                        
                        % Erode inverted saturation image
                        invertErosion = double(bwmorph(invert, 'erode', 2));
                        
                        % Dilate eroded image
                        dilatErosion = double(bwmorph(invertErosion, 'dilate', 7));
                        
                        % Fill holes in dilated image
                        fillHoles = imfill(dilatErosion, 'holes');
                        
                        % Invert filled image
                        invertFinal = imcomplement(fillHoles);
                        
                        % Binarize hue channel
                        binarizeHue = imbinarize(hueInHSV);
                        
                        % Combine binarized saturation and hue images
                        combineHueSat = invertFinal & binarizeHue;
                        
                        % Erode combined image
                        erosion = double(bwmorph(combineHueSat, 'erode', 3));
                        
                        % Dilate eroded image
                        dilation = double(bwmorph(erosion, 'dilate', 7));
                        
                        % Remove objects touching the border
                        nobord = imclearborder(dilation, 4);
                        
                        % Label connected components in the binary image
                        [labeledImage, numRegions] = bwlabel(nobord);
                        
                        % Get bounding boxes around each connected component
                        boundingBoxes = regionprops(labeledImage, 'BoundingBox');
                        
                        % Display input image and plot bounding boxes around each connected component
                        axis(app.UIAxes_3);
                        imagesc(inputImg, 'Parent', app.UIAxes_3);
                        hold(app.UIAxes_3, 'on');
                        numBoxes = 0; % Counter variable for number of bounding boxes
                        for k = 1:numRegions
                            thisBBox = boundingBoxes(k).BoundingBox;
                            rectangle('Position', thisBBox, 'EdgeColor', 'r', 'LineWidth', 1, 'Parent', app.UIAxes_3);
                            numBoxes = numBoxes + 1;
                            break; % Only plot the first bounding box
                        end
                        
                        % Display the number of bounding boxes
                        app.numBoundingBoxes.Text = num2str(numBoxes);

                        % Show the input image and the thresholded texture image
                        figure('Name','Pre-processing');
                        subplot(4, 3, 1),imshow(hue),title('Hue Image');
                        subplot(4, 3, 2),imshow(sat),title('Saturation Image');
                        subplot(4, 3, 3),imshow(val),title('Value Image');
                        subplot(4, 3, 4),imshow(invert),title('Invert Image');
                        subplot(4, 3, 5),imshow(invertErosion),title('Erode Inverted Saturation Image');
                        subplot(4, 3, 6),imshow(dilatErosion),title('Dilate Eroded Image');
                        subplot(4, 3, 7),imshow(fillHoles),title('Fill Holes in Dilated Image');
                        subplot(4, 3, 8),imshow(invertFinal),title('Invert Filled Image');
                        subplot(4, 3, 9),imshow(binarizeHue),title('Binarize Hue Image');
                        subplot(4, 3, 10),imshow(erosion),title('Final erosion Image');
                        subplot(4, 3, 11),imshow(dilation),title('Final dilation Image');
                        subplot(4, 3, 12),imshow(nobord),title('Final Clear Border Image');
                            
                    otherwise
                        disp('Fail');
                end
            else
                errordlg('Glove is not detected, please try again.', 'Error Message','modal');  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatexGloveTab = uitab(app.TabGroup);
            app.LatexGloveTab.Title = 'Latex Glove';
            app.UIAxes = uiaxes(app.LatexGloveTab);
            app.DefectinformationPanel = uipanel(app.LatexGloveTab);
            app.DefectinformationPanel.Title = 'Defect information';
            app.DetectedgloveLabel = uilabel(app.DefectinformationPanel);
            app.DetectedgloveLabel.Text = 'Detected glove:';
            app.DefectsLabel = uilabel(app.DefectinformationPanel);
            app.DefectsLabel.Text = 'Defects:';
            app.TextArea = uitextarea(app.DefectinformationPanel);
            app.TextArea.Editable = 'off';
            app.EditField = uieditfield(app.DefectinformationPanel, 'text');
            app.EditField.Editable = 'off';
            app.InitiateDetectionButton = uibutton(app.LatexGloveTab, 'push');
            app.InitiateDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateDetectionButtonPushed, true);
            app.InitiateDetectionButton.Text = 'Initiate Detection';
            app.ImportImageButton = uibutton(app.LatexGloveTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.LatexGloveDefectDetectionLabel = uilabel(app.LatexGloveTab);
            app.LatexGloveDefectDetectionLabel.Text = 'Latex Glove Defect Detection';
            app.ImageLabel = uilabel(app.LatexGloveTab);
            app.ImageLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.LatexGloveTab, 'text');
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
            app.ImageEditField.Editable = 'off';
            app.OrientationDropDownLabel = uilabel(app.LatexGloveTab);
            app.OrientationDropDownLabel.Text = 'Orientation:';
            app.OrientationDropDown = uidropdown(app.LatexGloveTab);
            app.OrientationDropDown.Items = {'Select', 'Palm', 'Side', 'Fingertip'};
            app.OrientationDropDown.Value = 'Select';
            app.ClothGloveTab = uitab(app.TabGroup);
            app.ClothGloveTab.Title = 'Cloth Glove';
            app.UIAxes_3 = uiaxes(app.ClothGloveTab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TextArea_2 = uitextarea(app.ClothGloveTab);
            app.DropDown = uidropdown(app.ClothGloveTab);
            app.DropDown.Items = {'Defect Type', 'Open Seam', 'Tearing', 'Unravelled Seam'};
            app.DropDown.Placeholder = 'Defect Type';
            app.DropDown.Value = 'Defect Type';
            app.DetectButton = uibutton(app.ClothGloveTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.DefectsDetectedLabel = uilabel(app.ClothGloveTab);
            app.DefectsDetectedLabel.Text = 'Defects Detected: ';
            app.numBoundingBoxes = uilabel(app.ClothGloveTab);
            app.numBoundingBoxes.Text = '';
            app.ClothGloveDefectDetectionLabel = uilabel(app.ClothGloveTab);
            app.ClothGloveDefectDetectionLabel.Text = 'Cloth Glove Defect Detection';
            app.ImportImageButton_3 = uibutton(app.ClothGloveTab, 'push');
            app.ImportImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_3Pushed, true);
            app.ImportImageButton_3.Text = 'Import Image';
            app.ImageEditField_3Label = uilabel(app.ClothGloveTab);
            app.ImageEditField_3Label.Text = 'Image:';
            app.ImageEditField_3 = uieditfield(app.ClothGloveTab, 'text');
            app.ImageEditField_3.Editable = 'off';
            app.FoamGloveTab = uitab(app.TabGroup);
            app.FoamGloveTab.Title = 'Foam Glove';
            app.UIAxes_2 = uiaxes(app.FoamGloveTab);
            app.ImportImageButton_2 = uibutton(app.FoamGloveTab, 'push');
            app.ImportImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_2Pushed, true);
            app.ImportImageButton_2.Text = 'Import Image';
            app.ImageEditField_2Label = uilabel(app.FoamGloveTab);
            app.ImageEditField_2Label.Text = 'Image:';
            app.ImageEditField_2 = uieditfield(app.FoamGloveTab, 'text');
            app.ImageEditField_2.Editable = 'off';
            app.FoamGloveDefectDetectionLabel = uilabel(app.FoamGloveTab);
            app.FoamGloveDefectDetectionLabel.Text = 'Foam Glove Defect Detection';
            app.SelectDetectionTypePanel = uipanel(app.FoamGloveTab);
            app.SelectDetectionTypePanel.Title = 'Select Detection Type';
            app.CutButton = uibutton(app.SelectDetectionTypePanel, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Text = 'Cut';
            app.SmallCutWristButton = uibutton(app.SelectDetectionTypePanel, 'push');
            app.SmallCutWristButton.ButtonPushedFcn = createCallbackFcn(app, @SmallCutWristButtonPushed, true);
            app.SmallCutWristButton.Text = 'Small Cut (Wrist)';
            app.WristTearingButton = uibutton(app.SelectDetectionTypePanel, 'push');
            app.WristTearingButton.ButtonPushedFcn = createCallbackFcn(app, @WristTearingButtonPushed, true);
            app.WristTearingButton.Text = 'Wrist Tearing';
            app.GlovesDefectDetectionGDDSystemLabel = uilabel(app.UIFigure);
            app.GlovesDefectDetectionGDDSystemLabel.Text = 'Gloves Defect Detection (GDD) System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gloves_defect_detection_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end